classdef start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SID_1542172UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StaticimageprocessingTab        matlab.ui.container.Tab
        ThresholdingPanel               matlab.ui.container.Panel
        ApplyhysteresisthresholdingCheckBox  matlab.ui.control.CheckBox
        ApplybandthresholdingCheckBox   matlab.ui.control.CheckBox
        WidthofcandidatebandLabel       matlab.ui.control.Label
        WidthofcandidatebandSlider      matlab.ui.control.Slider
        MorphologicaloperationsPanel    matlab.ui.container.Panel
        ErosionCheckBox                 matlab.ui.control.CheckBox
        ErosionStrelSize                matlab.ui.control.ListBox
        DilationCheckBox                matlab.ui.control.CheckBox
        DilationStrelSize               matlab.ui.control.ListBox
        StrelsizeLabel                  matlab.ui.control.Label
        StrelsizeLabel_2                matlab.ui.control.Label
        ProcessimageButton              matlab.ui.control.Button
        LoadimagePanel                  matlab.ui.container.Panel
        LoadfileButton                  matlab.ui.control.Button
        ResolutionLabel                 matlab.ui.control.Label
        FormatLabel                     matlab.ui.control.Label
        Resolution                      matlab.ui.control.Label
        Format                          matlab.ui.control.Label
        DisplayresultsPanel             matlab.ui.container.Panel
        OriginalimagevsgrayscaleimageCheckBox  matlab.ui.control.CheckBox
        HistogramsCheckBox              matlab.ui.control.CheckBox
        BinarymasksandisolatedRGBpixelsCheckBox  matlab.ui.control.CheckBox
        ColouredfingertipsongrayscaleimageCheckBox  matlab.ui.control.CheckBox
        AnnotatedfingertipscentresCheckBox  matlab.ui.control.CheckBox
        SkincontoursCheckBox            matlab.ui.control.CheckBox
        DetectedgestureCheckBox         matlab.ui.control.CheckBox
        A1Label                         matlab.ui.control.Label
        A2Label                         matlab.ui.control.Label
        A3B2B3Label                     matlab.ui.control.Label
        A4Label                         matlab.ui.control.Label
        A5Label                         matlab.ui.control.Label
        A6Label                         matlab.ui.control.Label
        B1B4Label                       matlab.ui.control.Label
        GrayscaleconversionPanel        matlab.ui.container.Panel
        ConversionmethodListBoxLabel    matlab.ui.control.Label
        ConversionmethodListBox         matlab.ui.control.ListBox
        ResizingPanel                   matlab.ui.container.Panel
        ResizetoSliderLabel             matlab.ui.control.Label
        ResizetoSlider                  matlab.ui.control.Slider
        ResizeimageCheckBox             matlab.ui.control.CheckBox
        FingerDetectionPanel            matlab.ui.container.Panel
        BentfingerthresholdSliderLabel  matlab.ui.control.Label
        BentfingerthresholdSlider       matlab.ui.control.Slider
        LivewebcamgesturerecognitionTab  matlab.ui.container.Tab
        StartgesturerecognitionButton   matlab.ui.control.Button
        SelectedcameraLabel             matlab.ui.control.Label
        CameraSelector                  matlab.ui.control.DropDown
        FindavailablecamerasButton      matlab.ui.control.Button
        AnalyseframeeverySliderLabel    matlab.ui.control.Label
        AnalyseframeeverySlider         matlab.ui.control.Slider
    end

    methods (Access = private)

        % Button pushed function: ProcessimageButton
        function ProcessimageButtonPushed(app, event)
            global image_matrix;
            
            figuresToDisplay = struct( 'A1', app.OriginalimagevsgrayscaleimageCheckBox.Value, ...
                                       'A2', app.HistogramsCheckBox.Value, ...
                                       'A3', app.BinarymasksandisolatedRGBpixelsCheckBox.Value, ...
                                       'A4', app.ColouredfingertipsongrayscaleimageCheckBox.Value, ...
                                       'A5', app.AnnotatedfingertipscentresCheckBox.Value, ...
                                       'A6', app.SkincontoursCheckBox.Value, ...
                                       'B', app.DetectedgestureCheckBox.Value, ...
                                       'BLive', false );
                                   
            resizeRatio = 1;
            
            if ( app.ResizeimageCheckBox.Value == true )
                resizeRatio = app.ResizetoSlider.Value;
                
            end % end if    
                                       
            settings = struct( 'resizeRatio', resizeRatio, ...
                               'grayscaleConversionMethod', app.ConversionmethodListBox.Value, ...
                               'applyHysteresisThresholding', app.ApplyhysteresisthresholdingCheckBox.Value, ...
                               'candidateBandRatio', app.WidthofcandidatebandSlider.Value, ...
                               'applyErosion' , app.ErosionCheckBox.Value, ...
                               'erosionStrelSize', str2double( app.ErosionStrelSize.Value ), ...
                               'applyDilation', app.DilationCheckBox.Value, ...
                               'dilationStrelSize', str2double( app.DilationStrelSize.Value ),  ...
                               'detectionThreshold', app.BentfingerthresholdSlider.Value );                        
            
            mainProgram( image_matrix, settings, figuresToDisplay );
        end

        % Button pushed function: LoadfileButton
        function LoadFileButtonPushed(app, event)
            global image_matrix;
            [ fileName, path ] = uigetfile( '*.jpg; *.JPG; *.png; *.PNG; *.bmp; *.BMP' );
            
            % Stop executing if file has not been loaded
            if ( fileName == 0 )
                return;
            end % end if
            
            image_matrix = imread ( fullfile( path,fileName ));
            fileInfo = imfinfo( fileName );
            
            app.Resolution.Text = fileInfo.Width + " x " + fileInfo.Height;
            app.ResolutionLabel.Visible = true;
            app.Format.Text = fileInfo.Format;
            app.FormatLabel.Visible = true;
            
            regularColour = app.ProcessimageButton.BackgroundColor;
            app.ProcessimageButton.BackgroundColor = app.LoadfileButton.BackgroundColor;
            
            app.LoadfileButton.BackgroundColor = regularColour;
            app.LoadfileButton.Text = 'Load next file';
            app.ProcessimageButton.Enable = true;
  
        end

        % Callback function
        function LoadfileButtonPushed(app, event)
            
        end

        % Callback function
        function ResolutionTextAreaValueChanged(app, event)

            
        end

        % Value changed function: ApplyhysteresisthresholdingCheckBox
        function ApplyhysteresisthresholdingCheckBoxValueChanged(app, event)

                app.WidthofcandidatebandSlider.Enable = app.ApplyhysteresisthresholdingCheckBox.Value;
        end

        % Button pushed function: StartgesturerecognitionButton
        function StartgesturerecognitionButtonPushed(app, event)
            camera = webcam( app.CameraSelector.Value );
            
            figuresToDisplay = struct( 'A1', false, ...
                                       'A2', false, ...
                                       'A3', false, ...
                                       'A4', false, ...
                                       'A5', false, ...
                                       'A6', false, ...
                                       'B', false, ...
                                       'BLive', true );
                                   
            % all computationally heavy processing is off, with no user-defined values used
            settings = struct( 'resizeRatio', 1, ...  
                               'grayscaleConversionMethod', 'Luminance', ...
                               'applyHysteresisThresholding', false, ...
                               'candidateBandRatio', -1, ...
                               'applyErosion' , false, ...
                               'erosionStrelSize', -1, ...
                               'applyDilation', false, ...
                               'dilationStrelSize', -1,  ...
                               'detectionThreshold', 0.7 );                        
            

            t = timer( 'TimerFcn', 'stat=false;', 'StartDelay',5);
            start( t );
            
            stat = true;
            while ( stat == true )
                
                image_matrix = snapshot( camera );
                mainProgram( image_matrix, settings, figuresToDisplay );
                pause( app.AnalyseframeeverySlider.Value )
                
            end % end while
        end

        % Button pushed function: FindavailablecamerasButton
        function FindavailablecamerasButtonPushed(app, event)
            app.CameraSelector.Items = webcamlist;
            
            if ( numel( app.CameraSelector.Items ) > 0)
                regularColour = app.StartgesturerecognitionButton.BackgroundColor;
                app.StartgesturerecognitionButton.BackgroundColor = app.FindavailablecamerasButton.BackgroundColor;
                app.FindavailablecamerasButton.BackgroundColor = regularColour;
                app.StartgesturerecognitionButton.Enable = 'on';
            end % end if

        end

        % Value changed function: ErosionCheckBox
        function ErosionCheckBoxValueChanged(app, event)
            value = app.ErosionCheckBox.Value;
            app.ErosionStrelSize.Enable = app.ErosionCheckBox.Value;
            app.StrelsizeLabel.Enable = app.ErosionCheckBox.Value;
        end

        % Value changed function: DilationCheckBox
        function DilationCheckBoxValueChanged(app, event)
            value = app.DilationCheckBox.Value;
            app.DilationStrelSize.Enable = app.DilationCheckBox.Value;
            app.StrelsizeLabel_2.Enable = app.DilationCheckBox.Value;
        end

        % Value changed function: ResizeimageCheckBox
        function ResizeimageCheckBoxValueChanged(app, event)
            value = app.ResizeimageCheckBox.Value;
            app.ResizetoSlider.Enable = app.ResizeimageCheckBox.Value;
        end

        % Value changed function: 
        % OriginalimagevsgrayscaleimageCheckBox
        function OriginalimagevsgrayscaleimageCheckBoxValueChanged(app, event)
            value = app.OriginalimagevsgrayscaleimageCheckBox.Value;
            app.A1Label.Visible = app.OriginalimagevsgrayscaleimageCheckBox.Value;
        end

        % Value changed function: HistogramsCheckBox
        function HistogramsCheckBoxValueChanged(app, event)
            value = app.HistogramsCheckBox.Value;
            app.A2Label.Visible = app.HistogramsCheckBox.Value;
        end

        % Value changed function: 
        % BinarymasksandisolatedRGBpixelsCheckBox
        function BinarymasksandisolatedRGBpixelsCheckBoxValueChanged(app, event)
            value = app.BinarymasksandisolatedRGBpixelsCheckBox.Value;
            app.A3B2B3Label.Visible = app.BinarymasksandisolatedRGBpixelsCheckBox.Value;
        end

        % Value changed function: 
        % ColouredfingertipsongrayscaleimageCheckBox
        function ColouredfingertipsongrayscaleimageCheckBoxValueChanged(app, event)
            value = app.ColouredfingertipsongrayscaleimageCheckBox.Value;
            app.A4Label.Visible = app.ColouredfingertipsongrayscaleimageCheckBox.Value;
        end

        % Value changed function: AnnotatedfingertipscentresCheckBox
        function AnnotatedfingertipscentresCheckBoxValueChanged(app, event)
            value = app.AnnotatedfingertipscentresCheckBox.Value;
            app.A5Label.Visible = app.AnnotatedfingertipscentresCheckBox.Value;
        end

        % Value changed function: SkincontoursCheckBox
        function SkincontoursCheckBoxValueChanged(app, event)
            value = app.SkincontoursCheckBox.Value;
            app.A6Label.Visible = app.SkincontoursCheckBox.Value;
        end

        % Value changed function: DetectedgestureCheckBox
        function DetectedgestureCheckBoxValueChanged(app, event)
            value = app.DetectedgestureCheckBox.Value;
            app.B1B4Label.Visible = app.DetectedgestureCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SID_1542172UIFigure = uifigure;
            app.SID_1542172UIFigure.Name = 'SID_1542172';
            app.TabGroup = uitabgroup(app.SID_1542172UIFigure);
            app.StaticimageprocessingTab = uitab(app.TabGroup);
            app.StaticimageprocessingTab.Title = '      Static image processing      ';
            app.ThresholdingPanel = uipanel(app.StaticimageprocessingTab);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.ApplyhysteresisthresholdingCheckBox = uicheckbox(app.ThresholdingPanel);
            app.ApplyhysteresisthresholdingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyhysteresisthresholdingCheckBoxValueChanged, true);
            app.ApplyhysteresisthresholdingCheckBox.Text = 'Apply hysteresis thresholding';
            app.ApplyhysteresisthresholdingCheckBox.Value = true;
            app.ApplybandthresholdingCheckBox = uicheckbox(app.ThresholdingPanel);
            app.ApplybandthresholdingCheckBox.Enable = 'off';
            app.ApplybandthresholdingCheckBox.Text = 'Apply band thresholding';
            app.ApplybandthresholdingCheckBox.Value = true;
            app.WidthofcandidatebandLabel = uilabel(app.ThresholdingPanel);
            app.WidthofcandidatebandLabel.Text = 'Width of candidate band';
            app.WidthofcandidatebandSlider = uislider(app.ThresholdingPanel);
            app.WidthofcandidatebandSlider.Limits = [0.01 0.2];
            app.WidthofcandidatebandSlider.MajorTicks = [0.01 0.05 0.1 0.15 0.2];
            app.WidthofcandidatebandSlider.MajorTickLabels = {'1%', '5%', '10%', '15%', '20%'};
            app.WidthofcandidatebandSlider.Value = 0.1;
            app.MorphologicaloperationsPanel = uipanel(app.StaticimageprocessingTab);
            app.MorphologicaloperationsPanel.Title = 'Morphological operations';
            app.ErosionCheckBox = uicheckbox(app.MorphologicaloperationsPanel);
            app.ErosionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErosionCheckBoxValueChanged, true);
            app.ErosionCheckBox.Text = 'Erosion';
            app.ErosionCheckBox.Value = true;
            app.ErosionStrelSize = uilistbox(app.MorphologicaloperationsPanel);
            app.ErosionStrelSize.Items = {'Small', 'Medium', 'Large'};
            app.ErosionStrelSize.ItemsData = {'2', ' 10', ' 30', ''};
            app.ErosionStrelSize.Value = '2';
            app.DilationCheckBox = uicheckbox(app.MorphologicaloperationsPanel);
            app.DilationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DilationCheckBoxValueChanged, true);
            app.DilationCheckBox.Text = 'Dilation';
            app.DilationCheckBox.Value = true;
            app.DilationStrelSize = uilistbox(app.MorphologicaloperationsPanel);
            app.DilationStrelSize.Items = {'Small', 'Medium', 'Large'};
            app.DilationStrelSize.ItemsData = {'2', '10', '30'};
            app.DilationStrelSize.Value = '10';
            app.StrelsizeLabel = uilabel(app.MorphologicaloperationsPanel);
            app.StrelsizeLabel.Text = 'Strel size';
            app.StrelsizeLabel_2 = uilabel(app.MorphologicaloperationsPanel);
            app.StrelsizeLabel_2.Text = 'Strel size';
            app.ProcessimageButton = uibutton(app.StaticimageprocessingTab, 'push');
            app.ProcessimageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessimageButtonPushed, true);
            app.ProcessimageButton.Enable = 'off';
            app.ProcessimageButton.Text = 'Process image';
            app.LoadimagePanel = uipanel(app.StaticimageprocessingTab);
            app.LoadimagePanel.Title = 'Load image';
            app.LoadfileButton = uibutton(app.LoadimagePanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.ResolutionLabel = uilabel(app.LoadimagePanel);
            app.ResolutionLabel.Visible = 'off';
            app.ResolutionLabel.Text = 'Resolution:';
            app.FormatLabel = uilabel(app.LoadimagePanel);
            app.FormatLabel.Visible = 'off';
            app.FormatLabel.Text = 'Format:';
            app.Resolution = uilabel(app.LoadimagePanel);
            app.Resolution.Text = '';
            app.Format = uilabel(app.LoadimagePanel);
            app.Format.Text = '';
            app.DisplayresultsPanel = uipanel(app.StaticimageprocessingTab);
            app.DisplayresultsPanel.Title = 'Display results';
            app.OriginalimagevsgrayscaleimageCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.OriginalimagevsgrayscaleimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalimagevsgrayscaleimageCheckBoxValueChanged, true);
            app.OriginalimagevsgrayscaleimageCheckBox.Text = 'Original image vs grayscale image';
            app.OriginalimagevsgrayscaleimageCheckBox.Value = true;
            app.HistogramsCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.HistogramsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramsCheckBoxValueChanged, true);
            app.HistogramsCheckBox.Text = 'Histograms';
            app.HistogramsCheckBox.Value = true;
            app.BinarymasksandisolatedRGBpixelsCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.BinarymasksandisolatedRGBpixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinarymasksandisolatedRGBpixelsCheckBoxValueChanged, true);
            app.BinarymasksandisolatedRGBpixelsCheckBox.Text = 'Binary masks and isolated RGB pixels';
            app.BinarymasksandisolatedRGBpixelsCheckBox.Value = true;
            app.ColouredfingertipsongrayscaleimageCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.ColouredfingertipsongrayscaleimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColouredfingertipsongrayscaleimageCheckBoxValueChanged, true);
            app.ColouredfingertipsongrayscaleimageCheckBox.Text = 'Coloured finger tips on grayscale image';
            app.ColouredfingertipsongrayscaleimageCheckBox.Value = true;
            app.AnnotatedfingertipscentresCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.AnnotatedfingertipscentresCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnnotatedfingertipscentresCheckBoxValueChanged, true);
            app.AnnotatedfingertipscentresCheckBox.Text = 'Annotated finger tips'' centres';
            app.AnnotatedfingertipscentresCheckBox.Value = true;
            app.SkincontoursCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.SkincontoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkincontoursCheckBoxValueChanged, true);
            app.SkincontoursCheckBox.Text = 'Skin contours';
            app.SkincontoursCheckBox.Value = true;
            app.DetectedgestureCheckBox = uicheckbox(app.DisplayresultsPanel);
            app.DetectedgestureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectedgestureCheckBoxValueChanged, true);
            app.DetectedgestureCheckBox.Text = 'Detected gesture';
            app.DetectedgestureCheckBox.Value = true;
            app.A1Label = uilabel(app.DisplayresultsPanel);
            app.A1Label.Text = 'A1';
            app.A2Label = uilabel(app.DisplayresultsPanel);
            app.A2Label.Text = 'A2';
            app.A3B2B3Label = uilabel(app.DisplayresultsPanel);
            app.A3B2B3Label.Text = 'A3, B2, B3';
            app.A4Label = uilabel(app.DisplayresultsPanel);
            app.A4Label.Text = 'A4';
            app.A5Label = uilabel(app.DisplayresultsPanel);
            app.A5Label.Text = 'A5';
            app.A6Label = uilabel(app.DisplayresultsPanel);
            app.A6Label.Text = 'A6';
            app.B1B4Label = uilabel(app.DisplayresultsPanel);
            app.B1B4Label.Text = 'B1, B4';
            app.GrayscaleconversionPanel = uipanel(app.StaticimageprocessingTab);
            app.GrayscaleconversionPanel.Title = 'Grayscale conversion';
            app.ConversionmethodListBoxLabel = uilabel(app.GrayscaleconversionPanel);
            app.ConversionmethodListBoxLabel.Text = 'Conversion method';
            app.ConversionmethodListBox = uilistbox(app.GrayscaleconversionPanel);
            app.ConversionmethodListBox.Items = {'Luminance', 'Luminosity', 'Luster', 'Intensity'};
            app.ConversionmethodListBox.Value = 'Luminance';
            app.ResizingPanel = uipanel(app.StaticimageprocessingTab);
            app.ResizingPanel.Title = 'Resizing';
            app.ResizetoSliderLabel = uilabel(app.ResizingPanel);
            app.ResizetoSliderLabel.Enable = 'off';
            app.ResizetoSliderLabel.Text = 'Resize to';
            app.ResizetoSlider = uislider(app.ResizingPanel);
            app.ResizetoSlider.Limits = [0.1 1];
            app.ResizetoSlider.MajorTicks = [0.1 0.2 0.3 0.5 1];
            app.ResizetoSlider.MajorTickLabels = {'10%', '20%', '30%', '50%', '100%'};
            app.ResizetoSlider.MinorTicks = [0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4 0.42 0.44 0.46 0.48 0.5 0.52 0.54 0.56 0.58 0.6 0.62 0.64 0.66 0.68 0.7 0.72 0.74 0.76 0.78 0.8 0.82 0.84 0.86 0.88 0.9 0.92 0.94 0.96 0.98 1];
            app.ResizetoSlider.Enable = 'off';
            app.ResizetoSlider.Value = 0.3;
            app.ResizeimageCheckBox = uicheckbox(app.ResizingPanel);
            app.ResizeimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResizeimageCheckBoxValueChanged, true);
            app.ResizeimageCheckBox.Text = 'Resize image';
            app.FingerDetectionPanel = uipanel(app.StaticimageprocessingTab);
            app.FingerDetectionPanel.Title = 'Finger Detection';
            app.BentfingerthresholdSliderLabel = uilabel(app.FingerDetectionPanel);
            app.BentfingerthresholdSliderLabel.Text = {'Bent finger'; 'threshold'};
            app.BentfingerthresholdSlider = uislider(app.FingerDetectionPanel);
            app.BentfingerthresholdSlider.Limits = [0.6 0.8];
            app.BentfingerthresholdSlider.MajorTicks = [0.6 0.65 0.7 0.75 0.8];
            app.BentfingerthresholdSlider.Value = 0.7;
            app.LivewebcamgesturerecognitionTab = uitab(app.TabGroup);
            app.LivewebcamgesturerecognitionTab.Title = '      Live webcam gesture recognition      ';
            app.StartgesturerecognitionButton = uibutton(app.LivewebcamgesturerecognitionTab, 'push');
            app.StartgesturerecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartgesturerecognitionButtonPushed, true);
            app.StartgesturerecognitionButton.Enable = 'off';
            app.StartgesturerecognitionButton.Text = 'Start gesture recognition';
            app.SelectedcameraLabel = uilabel(app.LivewebcamgesturerecognitionTab);
            app.SelectedcameraLabel.Text = 'Selected camera';
            app.CameraSelector = uidropdown(app.LivewebcamgesturerecognitionTab);
            app.CameraSelector.Items = {};
            app.CameraSelector.Value = {};
            app.FindavailablecamerasButton = uibutton(app.LivewebcamgesturerecognitionTab, 'push');
            app.FindavailablecamerasButton.ButtonPushedFcn = createCallbackFcn(app, @FindavailablecamerasButtonPushed, true);
            app.FindavailablecamerasButton.Text = 'Find available cameras';
            app.AnalyseframeeverySliderLabel = uilabel(app.LivewebcamgesturerecognitionTab);
            app.AnalyseframeeverySliderLabel.Text = 'Analyse frame every';
            app.AnalyseframeeverySlider = uislider(app.LivewebcamgesturerecognitionTab);
            app.AnalyseframeeverySlider.Limits = [0.5 2];
            app.AnalyseframeeverySlider.MajorTicks = [0.5 1 2];
            app.AnalyseframeeverySlider.MajorTickLabels = {'0.5 second', '1 second', '2 seconds'};
            app.AnalyseframeeverySlider.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = start

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SID_1542172UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SID_1542172UIFigure)
        end
    end
end